classdef visualizIR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        visualizIRUIFigure   matlab.ui.Figure
        TapsEditField        matlab.ui.control.EditField
        TapsEditFieldLabel   matlab.ui.control.Label
        TabGroup             matlab.ui.container.TabGroup
        ImpulseResponseTab   matlab.ui.container.Tab
        UIAxes1              matlab.ui.control.UIAxes
        MagnitudeTab         matlab.ui.container.Tab
        UIAxes2              matlab.ui.control.UIAxes
        PhaseTab             matlab.ui.container.Tab
        UIAxes3              matlab.ui.control.UIAxes
        GroupDelayTab        matlab.ui.container.Tab
        UIAxes4              matlab.ui.control.UIAxes
        PathDropDown         matlab.ui.control.DropDown
        PathDropDownLabel    matlab.ui.control.Label
        SpkSrcDropDown       matlab.ui.control.DropDown
        SpkSrcDropDownLabel  matlab.ui.control.Label
        FFTLenDropDown       matlab.ui.control.DropDown
        FFTLenDropDownLabel  matlab.ui.control.Label
        MicDropDown          matlab.ui.control.DropDown
        MicDropDownLabel     matlab.ui.control.Label
        RefEditField         matlab.ui.control.EditField
        RefEditFieldLabel    matlab.ui.control.Label
        ErrorEditField       matlab.ui.control.EditField
        ErrorEditFieldLabel  matlab.ui.control.Label
        SrcEditField         matlab.ui.control.EditField
        SrcEditFieldLabel    matlab.ui.control.Label
        SpkEditField         matlab.ui.control.EditField
        SpkEditFieldLabel    matlab.ui.control.Label
        fsEditField          matlab.ui.control.EditField
        fsEditFieldLabel     matlab.ui.control.Label
        FileName             matlab.ui.control.TextArea
        LoadButton           matlab.ui.control.Button
        StatusTextArea       matlab.ui.control.TextArea
        StatusTextAreaLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        impResponse;
        magResponse;
        phsResponse;

        fftLen;
    end
    
    properties (Access = public)
        data ;    % simulation input data
        priPath = struct('filt', [], ...
                         'mag',  [], ...
                         'phs',  [], ...
                         'grd',  []);
        secPath = struct('filt', [], ...
                         'mag',  [], ...
                         'phs',  [], ...
                         'grd',  []);
        refPath = struct('filt', [], ...
                         'mag',  [], ...
                         'phs',  [], ...
                         'grd',  []);
        numErr;
        numRef;
        numSrc;
        numSpk;
        numTaps;
        fs;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            clbLoadButtonPushed(app);
        end

        % Value changed function: PathDropDown
        function PathDropDownValueChanged(app, event)
            clbPathDropdownChanged(app);
        end

        % Value changed function: FFTLenDropDown
        function FFTLenDropDownValueChanged(app, event)
            clbFftLenDropdownChanged(app);            
        end

        % Value changed function: MicDropDown
        function MicDropDownValueChanged(app, event)
            clbMicDropdownChanged(app);
        end

        % Value changed function: SpkSrcDropDown
        function SpkSrcDropDownValueChanged(app, event)
            clbSpkSrcDropdownChanged(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.visualizIRUIFigure = uifigure('Visible', 'off');
            app.visualizIRUIFigure.Name = 'visualizIR';
            app.StatusTextAreaLabel = uilabel(app.visualizIRUIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.visualizIRUIFigure);
            app.StatusTextArea.Value = {'Ready!'};
            app.LoadButton = uibutton(app.visualizIRUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FileName = uitextarea(app.visualizIRUIFigure);
            app.fsEditFieldLabel = uilabel(app.visualizIRUIFigure);
            app.fsEditFieldLabel.Text = 'fs';
            app.fsEditField = uieditfield(app.visualizIRUIFigure, 'text');
            app.SpkEditFieldLabel = uilabel(app.visualizIRUIFigure);
            app.SpkEditFieldLabel.Text = 'Spk';
            app.SpkEditField = uieditfield(app.visualizIRUIFigure, 'text');
            app.SrcEditFieldLabel = uilabel(app.visualizIRUIFigure);
            app.SrcEditFieldLabel.Text = 'Src';
            app.SrcEditField = uieditfield(app.visualizIRUIFigure, 'text');
            app.ErrorEditFieldLabel = uilabel(app.visualizIRUIFigure);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.visualizIRUIFigure, 'text');
            app.RefEditFieldLabel = uilabel(app.visualizIRUIFigure);
            app.RefEditFieldLabel.Text = 'Ref';
            app.RefEditField = uieditfield(app.visualizIRUIFigure, 'text');
            app.MicDropDownLabel = uilabel(app.visualizIRUIFigure);
            app.MicDropDownLabel.Text = 'Mic';
            app.MicDropDown = uidropdown(app.visualizIRUIFigure);
            app.MicDropDown.Items = {};
            app.MicDropDown.ValueChangedFcn = createCallbackFcn(app, @MicDropDownValueChanged, true);
            app.MicDropDown.Value = {};
            app.FFTLenDropDownLabel = uilabel(app.visualizIRUIFigure);
            app.FFTLenDropDownLabel.Text = 'FFT Len';
            app.FFTLenDropDown = uidropdown(app.visualizIRUIFigure);
            app.FFTLenDropDown.Items = {'512', '1024', '2048', '4096'};
            app.FFTLenDropDown.ValueChangedFcn = createCallbackFcn(app, @FFTLenDropDownValueChanged, true);
            app.FFTLenDropDown.Value = '4096';
            app.SpkSrcDropDownLabel = uilabel(app.visualizIRUIFigure);
            app.SpkSrcDropDownLabel.Text = 'Spk/Src';
            app.SpkSrcDropDown = uidropdown(app.visualizIRUIFigure);
            app.SpkSrcDropDown.Items = {};
            app.SpkSrcDropDown.ValueChangedFcn = createCallbackFcn(app, @SpkSrcDropDownValueChanged, true);
            app.SpkSrcDropDown.Value = {};
            app.PathDropDownLabel = uilabel(app.visualizIRUIFigure);
            app.PathDropDownLabel.Text = 'Path';
            app.PathDropDown = uidropdown(app.visualizIRUIFigure);
            app.PathDropDown.Items = {};
            app.PathDropDown.ValueChangedFcn = createCallbackFcn(app, @PathDropDownValueChanged, true);
            app.PathDropDown.Value = {};
            app.TabGroup = uitabgroup(app.visualizIRUIFigure);
            app.ImpulseResponseTab = uitab(app.TabGroup);
            app.ImpulseResponseTab.Title = 'Impulse Response';
            app.UIAxes1 = uiaxes(app.ImpulseResponseTab);
            title(app.UIAxes1, 'Impulse Response')
            xlabel(app.UIAxes1, 'Time (ms)')
            ylabel(app.UIAxes1, 'Amplitude')
            zlabel(app.UIAxes1, 'Z')
            app.MagnitudeTab = uitab(app.TabGroup);
            app.MagnitudeTab.Title = 'Magnitude';
            app.UIAxes2 = uiaxes(app.MagnitudeTab);
            title(app.UIAxes2, 'Magnitude Response')
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'Magnitude (dB)')
            zlabel(app.UIAxes2, 'Z')
            app.PhaseTab = uitab(app.TabGroup);
            app.PhaseTab.Title = 'Phase';
            app.UIAxes3 = uiaxes(app.PhaseTab);
            title(app.UIAxes3, 'Phase Response')
            xlabel(app.UIAxes3, 'Frequency (Hz)')
            ylabel(app.UIAxes3, 'Angle (Radian)')
            zlabel(app.UIAxes3, 'Z')
            app.GroupDelayTab = uitab(app.TabGroup);
            app.GroupDelayTab.Title = 'Group Delay';
            app.UIAxes4 = uiaxes(app.GroupDelayTab);
            title(app.UIAxes4, 'Group Delay')
            xlabel(app.UIAxes4, 'Frequency (Hz)')
            ylabel(app.UIAxes4, 'Samples/radian')
            zlabel(app.UIAxes4, 'Z')
            app.TapsEditFieldLabel = uilabel(app.visualizIRUIFigure);
            app.TapsEditFieldLabel.Text = 'Taps';
            app.TapsEditField = uieditfield(app.visualizIRUIFigure, 'text');
            % Show the figure after all components are created
            app.visualizIRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualizIR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.visualizIRUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.visualizIRUIFigure)
        end
    end
end